classdef app_BaiTap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        C10EditField               matlab.ui.control.EditField
        C9EditField                matlab.ui.control.EditField
        C8EditField                matlab.ui.control.EditField
        KetQuaNoiSuyEditField      matlab.ui.control.NumericEditField
        KtQuNiSuyEditFieldLabel    matlab.ui.control.Label
        DaThucNoiSuyEditField      matlab.ui.control.EditField
        aThcNiSuyEditFieldLabel    matlab.ui.control.Label
        GiaTriCanNoiSuyEditField   matlab.ui.control.NumericEditField
        GiTrCnNiSuyEditFieldLabel  matlab.ui.control.Label
        NhapMangyEditField         matlab.ui.control.EditField
        NhpMngyEditFieldLabel      matlab.ui.control.Label
        NhapMangxEditField         matlab.ui.control.EditField
        NhpMngxEditFieldLabel      matlab.ui.control.Label
        x4EditField                matlab.ui.control.NumericEditField
        x4EditFieldLabel           matlab.ui.control.Label
        x3EditField                matlab.ui.control.NumericEditField
        x3EditFieldLabel           matlab.ui.control.Label
        x2EditField                matlab.ui.control.NumericEditField
        x2EditFieldLabel           matlab.ui.control.Label
        x1EditField                matlab.ui.control.NumericEditField
        x1EditFieldLabel           matlab.ui.control.Label
        b4EditField                matlab.ui.control.NumericEditField
        b3EditField                matlab.ui.control.NumericEditField
        b2EditField                matlab.ui.control.NumericEditField
        b1EditField                matlab.ui.control.NumericEditField
        NhpHSbEditFieldLabel       matlab.ui.control.Label
        NghiemHePTEditField        matlab.ui.control.EditField
        KetQuaKhacEditField        matlab.ui.control.EditField
        KtQuKhcEditFieldLabel      matlab.ui.control.Label
        HeSoPT1EditField           matlab.ui.control.EditField
        NhpHSPhngTrnhLabel         matlab.ui.control.Label
        SaiSoChoPhepEditField      matlab.ui.control.NumericEditField
        SaiSChoPhpEditFieldLabel   matlab.ui.control.Label
        GioiHanb1EditField         matlab.ui.control.NumericEditField
        GiiHnPhnLyNghimb1Label     matlab.ui.control.Label
        GioiHana1EditField         matlab.ui.control.NumericEditField
        GiiHnPhnLyNghima1Label     matlab.ui.control.Label
        KtQuEditFieldLabel         matlab.ui.control.Label
        KetQuaEditField            matlab.ui.control.NumericEditField
        NhapPhuongTrinhEditField   matlab.ui.control.EditField
        NhpPhngTrnhLabel           matlab.ui.control.Label
        SoLanLapEditField          matlab.ui.control.NumericEditField
        SLnLpEditFieldLabel        matlab.ui.control.Label
        ChonPhuongPhapListBox      matlab.ui.control.ListBox
        ChnPhngPhpListBoxLabel     matlab.ui.control.Label
        ChonBaiTapListBox          matlab.ui.control.ListBox
        ChnBiTpListBoxLabel        matlab.ui.control.Label
        MSSVEditField              matlab.ui.control.EditField
        MSSVEditFieldLabel         matlab.ui.control.Label
        TenSVEditField             matlab.ui.control.EditField
        HTnSVEditFieldLabel        matlab.ui.control.Label
        LopEditField               matlab.ui.control.EditField
        LpEditFieldLabel           matlab.ui.control.Label
        HeSoPT4EditField           matlab.ui.control.EditField
        HeSoPT3EditField           matlab.ui.control.EditField
        HeSoPT2EditField           matlab.ui.control.EditField
        RUNButton                  matlab.ui.control.Button
        AnhTheImage                matlab.ui.control.Image
        MonHocEditField            matlab.ui.control.EditField
        BaoCaoBaiTapEditField      matlab.ui.control.EditField
        LogoTruongImage            matlab.ui.control.Image
        DoThiUIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            f = app.NhapPhuongTrinhEditField.Value;
            f = str2func(['@(x)',f]);
            a = app.GioiHana1EditField.Value;
            b = app.GioiHanb1EditField.Value;
            saiso = app.SaiSoChoPhepEditField.Value;

            pt1 = str2num(app.HeSoPT1EditField.Value);
            pt2 = str2num(app.HeSoPT2EditField.Value);
            pt3 = str2num(app.HeSoPT3EditField.Value);
            pt4 = str2num(app.HeSoPT4EditField.Value);
            A = [pt1; pt2; pt3; pt4];
            b1 = app.b1EditField.Value;
            b2 = app.b2EditField.Value;
            b3 = app.b3EditField.Value;
            b4 = app.b4EditField.Value;
            bc2 = [b1; b2; b3; b4];

            temp = app.ChonBaiTapListBox.Value;
            switch temp
                case "3.2 Bài 7 Trang 103"
                    pp = app.ChonPhuongPhapListBox.Value;
                    if (pp == "Lặp")
                        [nghiem, solanlap] = Phuong_Phap_Lap(f, a, b, saiso);
                        fplot(app.DoThiUIAxes, f, [a, b], 'k*--');
                    elseif (pp == "Newton")
                        [nghiem, solanlap] = Phuong_Phap_Tiep_Tuyen(f, a, b, saiso);
                        fplot(app.DoThiUIAxes, f, [a, b], 'ro--');
                    elseif (pp == "Dây cung")
                        [nghiem, solanlap] = Phuong_Phap_Day_Cung(f, a, b, saiso);
                        fplot(app.DoThiUIAxes, f, [a, b], 'b+--');
                    end
             app.SoLanLapEditField.Value = solanlap;
             app.KetQuaEditField.Value = nghiem;
                case "4.2 Bài 2 Trang 158"
                    [nghiem] = Gauss_Jordan(A, bc2);
                    if strcmp(nghiem, 'Vô số nghiệm')
                        app.KetQuaKhacEditField.Value = "Vô Số Nghiệm";
                    elseif strcmp(nghiem, 'Vô nghiệm')
                        app.KetQuaKhacEditField.Value = "Vô Nghiệm";
                    else
                        app.x1EditField.Value = nghiem(1);
                        app.x2EditField.Value = nghiem(2);
                        app.x3EditField.Value = nghiem(3);
                        app.x4EditField.Value = nghiem(4);
                        app.KetQuaKhacEditField.Value = " ";
                    end
                case "5.2 Trang 189"
                    xa = str2num(app.NhapMangxEditField.Value);
                    ya = str2num(app.NhapMangyEditField.Value);
                    x = app.GiaTriCanNoiSuyEditField.Value;
                    if(1)
                        result = Lagrange_NS(xa, ya, x);
                        f_ns = Lagrange_DoThi_NS(xa, ya);
                    end
                    app.KetQuaNoiSuyEditField.Value = result;
                    app.DaThucNoiSuyEditField.Value = f_ns;
                    f_ns = str2func(['@(x)',f_ns]); 
                    fplot(app.DoThiUIAxes, f_ns, [xa(1), xa(end)], 'c--');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoThiUIAxes = uiaxes(app.UIFigure);
            title(app.DoThiUIAxes, 'Đồ Thị')
            xlabel(app.DoThiUIAxes, 'X')
            ylabel(app.DoThiUIAxes, 'Y')
            zlabel(app.DoThiUIAxes, 'Z')
            app.LogoTruongImage = uiimage(app.UIFigure);
            app.LogoTruongImage.ImageSource = fullfile(pathToMLAPP, 'hcmus.png');
            app.BaoCaoBaiTapEditField = uieditfield(app.UIFigure, 'text');
            app.BaoCaoBaiTapEditField.Value = 'BÁO CÁO BÀI TẬP';
            app.MonHocEditField = uieditfield(app.UIFigure, 'text');
            app.MonHocEditField.Value = 'Môn học: Phương Pháp Tính và Matlab';
            app.AnhTheImage = uiimage(app.UIFigure);
            app.AnhTheImage.ImageSource = fullfile(pathToMLAPP, 'AnhThe.jpg');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.HeSoPT2EditField = uieditfield(app.UIFigure, 'text');
            app.HeSoPT2EditField.Value = '3 11 2 4';
            app.HeSoPT3EditField = uieditfield(app.UIFigure, 'text');
            app.HeSoPT3EditField.Value = '3 2 3 4';
            app.HeSoPT4EditField = uieditfield(app.UIFigure, 'text');
            app.HeSoPT4EditField.Value = '1 1 1 0';
            app.LpEditFieldLabel = uilabel(app.UIFigure);
            app.LpEditFieldLabel.Text = 'Lớp';
            app.LopEditField = uieditfield(app.UIFigure, 'text');
            app.LopEditField.Value = '21DTV1';
            app.HTnSVEditFieldLabel = uilabel(app.UIFigure);
            app.HTnSVEditFieldLabel.Text = 'Họ Tên SV';
            app.TenSVEditField = uieditfield(app.UIFigure, 'text');
            app.TenSVEditField.Value = 'Nguyễn Tiến Đại';
            app.MSSVEditFieldLabel = uilabel(app.UIFigure);
            app.MSSVEditFieldLabel.Text = 'Mã Số SV';
            app.MSSVEditField = uieditfield(app.UIFigure, 'text');
            app.MSSVEditField.Value = '21200274';
            app.ChnBiTpListBoxLabel = uilabel(app.UIFigure);
            app.ChnBiTpListBoxLabel.Text = 'Chọn Bài Tập';
            app.ChonBaiTapListBox = uilistbox(app.UIFigure);
            app.ChonBaiTapListBox.Items = {'3.2 Bài 7 Trang 103', '4.2 Bài 2 Trang 158', '5.2 Trang 189', '6.1 Trang 220'};
            app.ChonBaiTapListBox.Value = '3.2 Bài 7 Trang 103';
            app.ChnPhngPhpListBoxLabel = uilabel(app.UIFigure);
            app.ChnPhngPhpListBoxLabel.Text = 'Chọn Phương Pháp';
            app.ChonPhuongPhapListBox = uilistbox(app.UIFigure);
            app.ChonPhuongPhapListBox.Items = {'Lặp', 'Newton', 'Dây cung'};
            app.ChonPhuongPhapListBox.Value = 'Lặp';
            app.SLnLpEditFieldLabel = uilabel(app.UIFigure);
            app.SLnLpEditFieldLabel.Text = 'Số Lần Lặp';
            app.SoLanLapEditField = uieditfield(app.UIFigure, 'numeric');
            app.NhpPhngTrnhLabel = uilabel(app.UIFigure);
            app.NhpPhngTrnhLabel.Text = 'Nhập Phương Trình';
            app.NhapPhuongTrinhEditField = uieditfield(app.UIFigure, 'text');
            app.NhapPhuongTrinhEditField.Value = 'x^4+3*x-20';
            app.KetQuaEditField = uieditfield(app.UIFigure, 'numeric');
            app.KtQuEditFieldLabel = uilabel(app.UIFigure);
            app.KtQuEditFieldLabel.Text = 'Kết Quả';
            app.GiiHnPhnLyNghima1Label = uilabel(app.UIFigure);
            app.GiiHnPhnLyNghima1Label.Text = 'Giới Hạn Phân Ly Nghiệm a1';
            app.GioiHana1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GioiHana1EditField.Value = 1;
            app.GiiHnPhnLyNghimb1Label = uilabel(app.UIFigure);
            app.GiiHnPhnLyNghimb1Label.Text = 'Giới Hạn Phân Ly Nghiệm b1';
            app.GioiHanb1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GioiHanb1EditField.Value = 2;
            app.SaiSChoPhpEditFieldLabel = uilabel(app.UIFigure);
            app.SaiSChoPhpEditFieldLabel.Text = 'Sai Số Cho Phép';
            app.SaiSoChoPhepEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaiSoChoPhepEditField.Value = 0.01;
            app.NhpHSPhngTrnhLabel = uilabel(app.UIFigure);
            app.NhpHSPhngTrnhLabel.Text = 'Nhập Hệ Số Phương Trình A ';
            app.HeSoPT1EditField = uieditfield(app.UIFigure, 'text');
            app.HeSoPT1EditField.Value = '6 -5 7 8';
            app.KtQuKhcEditFieldLabel = uilabel(app.UIFigure);
            app.KtQuKhcEditFieldLabel.Text = 'Kết Quả Khác';
            app.KetQuaKhacEditField = uieditfield(app.UIFigure, 'text');
            app.NghiemHePTEditField = uieditfield(app.UIFigure, 'text');
            app.NghiemHePTEditField.Value = 'Nghiệm Hệ Phương Trình';
            app.NhpHSbEditFieldLabel = uilabel(app.UIFigure);
            app.NhpHSbEditFieldLabel.Text = 'Nhập Hệ Số b';
            app.b1EditField = uieditfield(app.UIFigure, 'numeric');
            app.b1EditField.Value = 3;
            app.b2EditField = uieditfield(app.UIFigure, 'numeric');
            app.b2EditField.Value = 6;
            app.b3EditField = uieditfield(app.UIFigure, 'numeric');
            app.b3EditField.Value = 1;
            app.b4EditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x3EditFieldLabel = uilabel(app.UIFigure);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.UIFigure, 'numeric');
            app.x4EditFieldLabel = uilabel(app.UIFigure);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.UIFigure, 'numeric');
            app.NhpMngxEditFieldLabel = uilabel(app.UIFigure);
            app.NhpMngxEditFieldLabel.Text = 'Nhập Mảng x';
            app.NhapMangxEditField = uieditfield(app.UIFigure, 'text');
            app.NhapMangxEditField.Value = '-1 0 1';
            app.NhpMngyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpMngyEditFieldLabel.Text = 'Nhập Mảng y';
            app.NhapMangyEditField = uieditfield(app.UIFigure, 'text');
            app.NhapMangyEditField.Value = '1/3 1 3';
            app.GiTrCnNiSuyEditFieldLabel = uilabel(app.UIFigure);
            app.GiTrCnNiSuyEditFieldLabel.Text = 'Giá Trị Cần Nội Suy';
            app.GiaTriCanNoiSuyEditField = uieditfield(app.UIFigure, 'numeric');
            app.GiaTriCanNoiSuyEditField.Value = 0.5;
            app.aThcNiSuyEditFieldLabel = uilabel(app.UIFigure);
            app.aThcNiSuyEditFieldLabel.Text = 'Đa Thức Nội Suy';
            app.DaThucNoiSuyEditField = uieditfield(app.UIFigure, 'text');
            app.KtQuNiSuyEditFieldLabel = uilabel(app.UIFigure);
            app.KtQuNiSuyEditFieldLabel.Text = 'Kết Quả Nội Suy';
            app.KetQuaNoiSuyEditField = uieditfield(app.UIFigure, 'numeric');
            app.C8EditField = uieditfield(app.UIFigure, 'text');
            app.C8EditField.Value = 'CHƯƠNG 8';
            app.C9EditField = uieditfield(app.UIFigure, 'text');
            app.C9EditField.Value = 'CHƯƠNG 9';
            app.C10EditField = uieditfield(app.UIFigure, 'text');
            app.C10EditField.Value = 'CHƯƠNG 10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_BaiTap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end